classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InputSourceEditFieldLabel  matlab.ui.control.Label
        InputSourceEditField       matlab.ui.control.EditField
        OutputBlackEditFieldLabel  matlab.ui.control.Label
        OutputBlackEditField       matlab.ui.control.EditField
        OutputWhiteEditFieldLabel  matlab.ui.control.Label
        OutputWhiteEditField       matlab.ui.control.EditField
        SubmitButton               matlab.ui.control.StateButton
    end

    methods (Access = private)

        % Value changed function: SubmitButton
        function SubmitButtonValueChanged(app, event)
            clc;
            hApp = gcf; % save the handle of the main form
            hWB = waitbar(0, 'Please wait...');
            
            input = app.InputSourceEditField.Value;
            output_black = app.OutputBlackEditField.Value;
            output_white = app.OutputWhiteEditField.Value;
            
            file = dir(fullfile(input, '*.jpg'));
            
            limit = length(file);
            for pointer = 1:limit
                set(0, 'CurrentFigure', hWB);
                waitbar((pointer/limit), hWB, strrep(strcat('autocrop:', file(pointer).name), '_', '\_'));
                set(0, 'CurrentFigure', hApp);
                
                autocrop( ...
                    fullfile(input, file(pointer).name), ...
                    fullfile(output_black, file(pointer).name), ...
                    fullfile(output_white, file(pointer).name) ...
                );
            end
            
            close(hWB);
            clear all;
            msgbox('Done!');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputSourceEditFieldLabel = uilabel(app.UIFigure);
            app.InputSourceEditFieldLabel.Text = 'Input Source';
            app.InputSourceEditField = uieditfield(app.UIFigure, 'text');
            app.InputSourceEditField.Value = 'C:\Nita\Thesis\image\autocrop-source';
            app.OutputBlackEditFieldLabel = uilabel(app.UIFigure);
            app.OutputBlackEditFieldLabel.Text = 'Output Black';
            app.OutputBlackEditField = uieditfield(app.UIFigure, 'text');
            app.OutputBlackEditField.Value = 'C:\Nita\Thesis\image\autocrop-black';
            app.OutputWhiteEditFieldLabel = uilabel(app.UIFigure);
            app.OutputWhiteEditFieldLabel.Text = 'Output White';
            app.OutputWhiteEditField = uieditfield(app.UIFigure, 'text');
            app.OutputWhiteEditField.Value = 'C:\Nita\Thesis\image\autocrop-white';
            app.SubmitButton = uibutton(app.UIFigure, 'state');
            app.SubmitButton.ValueChangedFcn = createCallbackFcn(app, @SubmitButtonValueChanged, true);
            app.SubmitButton.Text = 'Submit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end