classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataClusterUIFigure       matlab.ui.Figure
        DataTrainEditFieldLabel   matlab.ui.control.Label
        DataTrainEditField        matlab.ui.control.EditField
        DataTestEditFieldLabel    matlab.ui.control.Label
        DataTestEditField         matlab.ui.control.EditField
        DataExpectEditFieldLabel  matlab.ui.control.Label
        DataExpectEditField       matlab.ui.control.EditField
        CSVOutputEditFieldLabel   matlab.ui.control.Label
        CSVOutputEditField        matlab.ui.control.EditField
        KNearestNeighborCheckBox  matlab.ui.control.CheckBox
        ArtificialNeuralNetworkCheckBox  matlab.ui.control.CheckBox
        BayesianCheckBox          matlab.ui.control.CheckBox
        SubmitButton              matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            data_cluseter( ...
                app.DataTrainEditField.Value, ...
                app.DataTestEditField.Value, ...
                app.DataExpectEditField.Value, ...
                app.CSVOutputEditField.Value, ...
                app.KNearestNeighborCheckBox.Value, ...
                app.ArtificialNeuralNetworkCheckBox.Value, ...
                app.BayesianCheckBox.Value ...
            );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataClusterUIFigure = uifigure;
            app.DataClusterUIFigure.Name = 'Data Cluster';
            app.DataTrainEditFieldLabel = uilabel(app.DataClusterUIFigure);
            app.DataTrainEditFieldLabel.Text = 'Data Train';
            app.DataTrainEditField = uieditfield(app.DataClusterUIFigure, 'text');
            app.DataTrainEditField.Value = 'C:\Nita\Dropbox\Thesis\image\data-train';
            app.DataTestEditFieldLabel = uilabel(app.DataClusterUIFigure);
            app.DataTestEditFieldLabel.Text = 'Data Test';
            app.DataTestEditField = uieditfield(app.DataClusterUIFigure, 'text');
            app.DataTestEditField.Value = 'C:\Nita\Dropbox\Thesis\image\data-test';
            app.DataExpectEditFieldLabel = uilabel(app.DataClusterUIFigure);
            app.DataExpectEditFieldLabel.Text = 'Data Expect';
            app.DataExpectEditField = uieditfield(app.DataClusterUIFigure, 'text');
            app.DataExpectEditField.Value = 'C:\Nita\Dropbox\Thesis\image\data-expect';
            app.CSVOutputEditFieldLabel = uilabel(app.DataClusterUIFigure);
            app.CSVOutputEditFieldLabel.Text = 'CSV Output';
            app.CSVOutputEditField = uieditfield(app.DataClusterUIFigure, 'text');
            app.CSVOutputEditField.Value = 'C:\Nita\Dropbox\Thesis\datacluster.csv';
            app.KNearestNeighborCheckBox = uicheckbox(app.DataClusterUIFigure);
            app.KNearestNeighborCheckBox.Text = 'K-Nearest Neighbor';
            app.KNearestNeighborCheckBox.Value = true;
            app.ArtificialNeuralNetworkCheckBox = uicheckbox(app.DataClusterUIFigure);
            app.ArtificialNeuralNetworkCheckBox.Text = 'Artificial Neural Network';
            app.ArtificialNeuralNetworkCheckBox.Value = true;
            app.BayesianCheckBox = uicheckbox(app.DataClusterUIFigure);
            app.BayesianCheckBox.Text = 'Bayesian';
            app.BayesianCheckBox.Value = true;
            app.SubmitButton = uibutton(app.DataClusterUIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataClusterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataClusterUIFigure)
        end
    end
end